classdef SUT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TamaodelelementoestructurantesegmentacinLabel  matlab.ui.control.Label
        TamaodelelementoescturantesuavizadoLabel  matlab.ui.control.Label
        CambiosaplicadosLabel           matlab.ui.control.Label
        ResultadosLabel                 matlab.ui.control.Label
        ImagenoriginalLabel             matlab.ui.control.Label
        reaparaloscomponentesconexosaencontrarLabel  matlab.ui.control.Label
        AreaEditField                   matlab.ui.control.NumericEditField
        TonosmnimosSpinner              matlab.ui.control.Spinner
        TonosmnimosSpinnerLabel         matlab.ui.control.Label
        TonosmximosSpinner              matlab.ui.control.Spinner
        TonosmximosSpinnerLabel         matlab.ui.control.Label
        SegmentarButton                 matlab.ui.control.Button
        ModelodecontornosactivosButton  matlab.ui.control.Button
        ProcesoLamp                     matlab.ui.control.Lamp
        ProcesoLampLabel                matlab.ui.control.Label
        StrelsegmetacinSpinner          matlab.ui.control.Spinner
        UmbralSpinner                   matlab.ui.control.Spinner
        UmbralaaplicarSlider            matlab.ui.control.Slider
        UmbralaaplicarLabel             matlab.ui.control.Label
        StrelsuavisadoSpinner           matlab.ui.control.Spinner
        DifusinanisotrpicaCheckBox      matlab.ui.control.CheckBox
        GaussianoCheckBox               matlab.ui.control.CheckBox
        FiltrosLabel                    matlab.ui.control.Label
        ClaheCheckBox                   matlab.ui.control.CheckBox
        AgregarimagenButton             matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        UIAxesChanged                   matlab.ui.control.UIAxes
        UIAxesResults                   matlab.ui.control.UIAxes
        UIAxesOriginal                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Original; % Imagen Original**
        restructuredImage; %Imagen con tonos de extremos modificados
        filteredImage; %Imagen con filtros aplicados
        smothFixedImage; %Imagen con suavizado
        hasImage=0; 
        needContour;
    end
    
    methods (Access = public)
        function Img = reestructImage(app, imagen)

            % *HECUALIZACIÓN DEL HISTOGRAMA
            Ihecu = histeq(imagen); %se ecualiza la imagen en escala de grises
            [cantidades,tonos] = imhist(Ihecu); % se obtiene el histograma de la nueva imagen 
            
            % *REESTRUCTURACIÓN DE LA IMÁGEN (NEGROS Y BLANCOS)
            [rows, cols]=size(Ihecu); % Se obtiene el tamaño de la imágen hecualizada
            Img=Ihecu;  % Se crea una copia de la imágen hecualizada
            maximo = max(cantidades(1:app.TonosmnimosSpinner.Value)); % Obtiene la cantidad del tono entre 0 y 100
            maximo_maximo=app.TonosmximosSpinner.Value;
            indice=0; % Indice para indicar dos tonos más adelate de los más comunes.
            
            % ARREGLO PARA BUSCAR 2 TONOS MÁS ARRIBA DE AQUEL QUE TIENE MÁS
            TON= 2;     %Modificar para cambiar cantidad de tonos siguientes a tomar en cuenta.
            for i=1:length(tonos)
             
             if(indice<0 && cantidades(i)>0)
                
                if(indice==-1)
                 indice=i;
                 break;
                end
            
                indice=indice+1;
            
             end
             if(cantidades(i)==maximo)
                 indice=-TON-1;             
             end
            end
            
            % CONVERTIR A NEGRO VALORES MENORES AL DEL INDICE OBTENIDO
            for i=1:rows
                for j=1:cols
                    pl = Img(i,j);
                    if(pl<=indice)
                        Img(i,j)= 255;
                    elseif(pl >= maximo_maximo)
                        Img(i,j)= 255;
                    else
                        Img(i,j)=pl;
                    end
                end
            end

            app.restructuredImage=Img;
            imshow(Img,'Parent',app.UIAxesChanged);
end
%  *******************************************   
        function image = showFilter(app, image)
%             image=app.Original;
            if(app.ClaheCheckBox.Value==1)
                image = adapthisteq(image);
            end
            if(app.GaussianoCheckBox.Value==1)
                    image=imgaussfilt(image);
            end
            if(app.DifusinanisotrpicaCheckBox.Value==1)
                    image=im2double(imdiffusefilt(image));
            end

            app.filteredImage=image;
            imshow(image,'Parent',app.UIAxesChanged)
        end
 % *********************************************  
        function image = smothImage(app, image)
            se = strel('disk',app.StrelsuavisadoSpinner.Value);
            Ie = imerode(image,se);
            Iobr = imreconstruct(Ie,image);
            Iobrd = imdilate(Iobr,se);
            Iobrcbr = imreconstruct(imcomplement(Iobrd),imcomplement(Iobr));
            image = imcomplement(Iobrcbr);
            app.smothFixedImage=image;
            imshow(image,'Parent',app.UIAxesChanged)
        end
 % *********************************************       
        function image = segmentation(app, image)
            deactivateAll(app, 1)
            app.ProcesoLamp.Color=[1 1 0];
            pause(.2);

            [rows,cols]=size(image);
            %* Llama al spiner con el umbral
            J = imbinarize(image,app.UmbralSpinner.Value); 
            %* Llama al spiner para modificar el strel
            SE = strel('disk' ,app.StrelsegmetacinSpinner.Value ); 
            
            CC = bwconncomp(J, 6);
            n_obj= CC.NumObjects;
%             pixels_obj_1 = CC.PixelIdxList{1};
            im_copy = J;
            area_threshold = 10000;
            for n = 1:n_obj
                [area_obj, x ] = size(CC.PixelIdxList{n});
                if(area_obj < area_threshold)
                    for i = 1:area_obj
                        [r,c ] = IndexToCoordinates(CC.PixelIdxList{n}(i), rows);
                        im_copy(r,c) = 0;
                    end
                end
            end
            
            im_copy = imclose(im_copy,SE);
            im_copy = imcomplement(im_copy);

            new_CC = bwconncomp(im_copy,8);
            new_n_obj = new_CC.NumObjects;
%             seg = im_copy;
            CC_boundaries = bwboundaries(im_copy, 8);
            stats =  regionprops(new_CC, 'all');
            testicles = zeros(rows, cols); %Declara la matriz

            try
                for n=1:new_n_obj
                    % Calculo de parametros para identificar figuras
                    area = stats(n).Area;
                    [perimeter1, x] = size(CC_boundaries{n}); % Calculo de perimetro 2D
                
                    compacity_coeficcient = (2*pi*sqrt(area/pi))/perimeter1; % Calculo de coeficiente de compacidad
                    
                        % CIRCULO 
                    if(compacity_coeficcient > .90 && area > app.AreaEditField.Value) % condicional para identificar objet
                        centro = stats.Centroid;
                        disp(centro)
                        for i=1: area
                            [r,c] = IndexToCoordinates(new_CC.PixelIdxList{n}(i), rows);
                            testicles(r,c) = 255;
                        end
                    end 
                end 

                pause(.1);
            
                x=round(centro(1));
                y=round(centro(2));
                I = regiongrowing(im2double(testicles), x , y, 0.1); 
                prueba = im2double(app.Original)+I;
                
%                 B = imoverlay(J, I, 'red');
                imshow(prueba,'Parent',app.UIAxesResults)
                app.ProcesoLamp.Color=[0 1 0];
                enableContour(app,1);
                deactivateAll(app,0)
            catch
                msgbox('No encontramos de manera automática alguna segmentación. Porfavor si quieres seguir con la segmentación por el modelo de contorno activo presiona el botón correspondiente.','Error','warn');
                uiwait(msgbox);
                disp('Program execution resumed.');
                imshow(image,'Parent',app.UIAxesResults)
                app.ProcesoLamp.Color=[1 0 0];
                enableContour(app,1);
                deactivateAll(app,0);
            end
                
        end
%     ***********************************

        
function image = changeOriginalPixel(app, binary)
            image = app.Original;
            [rows,cols] = size(image);
            for i=1:rows
                for j=1:cols
                    if(binary(i,j)==0)
                        image(i,j)=255;
                    end
                end
            end
%             image=binary;
        end
    end
    methods (Access = private)
        
        function enableContour(app, bool)
            if(bool==1)
                app.ModelodecontornosactivosButton.Enable="on";
            else
                app.ModelodecontornosactivosButton.Enable="off";
            end
            
        end
        
        function deactivateAll(app, bool)
            if(bool==1)
                app.AgregarimagenButton.Enable="off";
                app.ClaheCheckBox.Enable="off";
                app.DifusinanisotrpicaCheckBox.Enable="off";
                app.GaussianoCheckBox.Enable="off";
                app.StrelsegmetacinSpinner.Enable="off";
                app.StrelsuavisadoSpinner.Enable="off";
                app.UmbralSpinner.Enable="off";
                app.UmbralaaplicarSlider.Enable="off";
                app.SegmentarButton.Enable="off";
                app.TonosmnimosSpinner.Enable="off";
                app.TonosmximosSpinner.Enable="off";
                app.AreaEditField.Enable="off";
                app.ModelodecontornosactivosButton.Enable="off";
            else 
                app.AgregarimagenButton.Enable="on";
                app.ClaheCheckBox.Enable="on";
                app.DifusinanisotrpicaCheckBox.Enable="on";
                app.GaussianoCheckBox.Enable="on";
                app.StrelsegmetacinSpinner.Enable="on";
                app.StrelsuavisadoSpinner.Enable="on";
                app.UmbralSpinner.Enable="on";
                app.UmbralaaplicarSlider.Enable="on";
                app.SegmentarButton.Enable="on";
                app.TonosmnimosSpinner.Enable="on";
                app.TonosmximosSpinner.Enable="on";
                app.AreaEditField.Enable="on";
                app.ModelodecontornosactivosButton.Enable="on";
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AgregarimagenButton
        function AgregarimagenButtonPushed(app, event)
            
            close all
            [file,path] = uigetfile({'*.png;*.jpg','Images(*.png,*.jpg'});
            if(path == 0)
%                 errordlg('Parece que hay un problema la lectura del archivo','File Error');
            else
                if(endsWith(file, '.png') || endsWith(file, '.jpg'))
                    %% Algoritmo de imagen 

                    imagen = strcat(path,file);
                    imagen= imread(imagen);
                    imagen = rgb2gray(imagen);
                    imshow(imagen,'Parent',app.UIAxesOriginal)
                    app.Original=imagen;
                    app.hasImage=1;

                    % *REESTRUCTURACIÓN DE LA IMÁGEN (NEGROS Y BLANCOS)
                    Img = reestructImage(app,imagen);
                    % *APLICACIÓN DE FILTROS
                    Im = showFilter(app, Img);
                    % *SUAVISADO DE LA IMAGEN
                    I = smothImage(app,Im); 
                    % *SEGMENTACIÓN
                    segmentation(app,I); 
                    
                end
            end
        end

        % Value changed function: ClaheCheckBox
        function ClaheCheckBoxValueChanged(app, event)
            
            if(app.hasImage~=0) 
                Img = reestructImage(app,app.Original);
                showFilter(app,Img);
%                 Im = smothImage(app,Im);     
%                 segmentation(app,Im);
                
            end

        end

        % Value changed function: GaussianoCheckBox
        function GaussianoCheckBoxValueChanged(app, event)
            if(app.hasImage~=0) 
                Img = reestructImage(app,app.Original);
                showFilter(app,Img);
%                 Im = smothImage(app,Im);     
%                 segmentation(app,Im);
            end
        end

        % Value changed function: DifusinanisotrpicaCheckBox
        function DifusinanisotrpicaCheckBoxValueChanged(app, event)
            if(app.hasImage~=0) 
                Img = reestructImage(app,app.Original);
                showFilter(app,Img);
%                 Im = smothImage(app,Im);     
%                 segmentation(app,Im);
            end
        end

        % Value changed function: UmbralaaplicarSlider
        function UmbralaaplicarSliderValueChanged(app, event)
            if(app.hasImage~=0) 
                value = app.UmbralaaplicarSlider.Value;
                app.UmbralSpinner.Value=value;
%                 segmentation(app,app.smothFixedImage);
            end
        end

        % Value changed function: StrelsuavisadoSpinner
        function StrelsuavisadoSpinnerValueChanged(app, event)
            if(app.hasImage~=0) 
            %             value = app.StrelsuavisadoSpinner.Value;
                smothImage(app,app.filteredImage);
%                 imshow(im,'Parent',app.UIAxesChanged)
%                 segmentation(app,im);
            end

        end

        % Value changed function: UmbralSpinner
        function UmbralSpinnerValueChanged(app, event)
            if(app.hasImage~=0) 
                value = app.UmbralSpinner.Value;
                app.UmbralaaplicarSlider.Value=value;
%                 segmentation(app,app.smothFixedImage);
            end

        end

        % Value changed function: StrelsegmetacinSpinner
        function StrelsegmetacinSpinnerValueChanged(app, event)
            if(app.hasImage~=0) 
            %             value = app.StrelsegmetacinSpinner.Value;
%             segmentation(app,app.smothFixedImage);
            end
        end

        % Button pushed function: ModelodecontornosactivosButton
        function ModelodecontornosactivosButtonPushed(app, event)
            if(app.hasImage~=0) 
                app.ProcesoLamp.Color=[1 1 0];
                image=app.smothFixedImage;
                [rows,cols] = size(image);
                figure(1)
                imshow(image)
                r = drawcircle;
                mask = createMask(r);
                bw = activecontour(app.smothFixedImage,mask,200,'edge');
                hold on;
                visboundaries(bw,'Color','r');

                new_CC = bwconncomp(bw,8);
                stats =  regionprops(new_CC, 'all');
                x = round(stats(1).Centroid(1));
                y = round(stats(1).Centroid(2));
                text(x, y, "b*",'Color','blue','FontSize',14);
                testicles = zeros(rows, cols);
                complement = im2double(imcomplement(image));

                I = regiongrowing(complement, x , y, 0.4);
                for i = 1:rows
                    for i2 = 1:cols
                        if(I(i,i2) ~= complement(i,i2) && bw(i,i2) == 1)
                            testicles(i,i2) = 255;
                        end
                    end
                end
                img = changeOriginalPixel(app,testicles);
                close all
                imshow(img,'Parent',app.UIAxesResults)
                app.ProcesoLamp.Color=[0 1 0];
%                 imshow(img)
            end
        end

        % Button pushed function: SegmentarButton
        function SegmentarButtonPushed(app, event)
            if(app.hasImage~=0) 
                Img = reestructImage(app,app.Original);
                Im=showFilter(app,Img);
                Im=smothImage(app,Im);
                segmentation(app,Im);
            end
        end

        % Value changed function: TonosmnimosSpinner
        function TonosmnimosSpinnerValueChanged(app, event)
            if(app.hasImage~=0) 
                value = app.TonosmnimosSpinner.Value;
                compare = app.TonosmximosSpinner.Value;
                if(value>=compare)
                 app.TonosmnimosSpinner.Value=compare-1;
                end
                reestructImage(app,app.Original);
            end
        end

        % Value changed function: TonosmximosSpinner
        function TonosmximosSpinnerValueChanged(app, event)
            if(app.hasImage~=0) 
                value = app.TonosmximosSpinner.Value;
                compare = app.TonosmnimosSpinner.Value;
                if(value<=compare)
                    app.TonosmximosSpinner.Value=compare+1;
                end
                reestructImage(app,app.Original);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesOriginal = uiaxes(app.UIFigure);
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesResults = uiaxes(app.UIFigure);
            app.UIAxesResults.XTick = [];
            app.UIAxesResults.YTick = [];
            app.UIAxesChanged = uiaxes(app.UIFigure);
            app.UIAxesChanged.XTick = [];
            app.UIAxesChanged.YTick = [];
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Text = '';
            app.AgregarimagenButton = uibutton(app.UIFigure, 'push');
            app.AgregarimagenButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarimagenButtonPushed, true);
            app.AgregarimagenButton.Text = 'Agregar imagen';
            app.ClaheCheckBox = uicheckbox(app.UIFigure);
            app.ClaheCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClaheCheckBoxValueChanged, true);
            app.ClaheCheckBox.Text = 'Clahe';
            app.ClaheCheckBox.Value = true;
            app.FiltrosLabel = uilabel(app.UIFigure);
            app.FiltrosLabel.Enable = 'off';
            app.FiltrosLabel.Text = 'Filtros';
            app.GaussianoCheckBox = uicheckbox(app.UIFigure);
            app.GaussianoCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussianoCheckBoxValueChanged, true);
            app.GaussianoCheckBox.Text = 'Gaussiano';
            app.GaussianoCheckBox.Value = true;
            app.DifusinanisotrpicaCheckBox = uicheckbox(app.UIFigure);
            app.DifusinanisotrpicaCheckBox.ValueChangedFcn = createCallbackFcn(app, @DifusinanisotrpicaCheckBoxValueChanged, true);
            app.DifusinanisotrpicaCheckBox.Text = 'Difusión anisotrópica';
            app.DifusinanisotrpicaCheckBox.Value = true;
            app.StrelsuavisadoSpinner = uispinner(app.UIFigure);
            app.StrelsuavisadoSpinner.Limits = [4 12];
            app.StrelsuavisadoSpinner.RoundFractionalValues = 'on';
            app.StrelsuavisadoSpinner.ValueChangedFcn = createCallbackFcn(app, @StrelsuavisadoSpinnerValueChanged, true);
            app.StrelsuavisadoSpinner.Value = 10;
            app.UmbralaaplicarLabel = uilabel(app.UIFigure);
            app.UmbralaaplicarLabel.Text = {'Umbral '; 'a '; 'aplicar'};
            app.UmbralaaplicarSlider = uislider(app.UIFigure);
            app.UmbralaaplicarSlider.Limits = [0 1];
            app.UmbralaaplicarSlider.ValueChangedFcn = createCallbackFcn(app, @UmbralaaplicarSliderValueChanged, true);
            app.UmbralaaplicarSlider.Value = 0.6;
            app.UmbralSpinner = uispinner(app.UIFigure);
            app.UmbralSpinner.Step = 0.05;
            app.UmbralSpinner.Limits = [0 1];
            app.UmbralSpinner.ValueDisplayFormat = '%5.2f';
            app.UmbralSpinner.ValueChangedFcn = createCallbackFcn(app, @UmbralSpinnerValueChanged, true);
            app.UmbralSpinner.Value = 0.6;
            app.StrelsegmetacinSpinner = uispinner(app.UIFigure);
            app.StrelsegmetacinSpinner.Limits = [4 12];
            app.StrelsegmetacinSpinner.RoundFractionalValues = 'on';
            app.StrelsegmetacinSpinner.ValueChangedFcn = createCallbackFcn(app, @StrelsegmetacinSpinnerValueChanged, true);
            app.StrelsegmetacinSpinner.Value = 12;
            app.ProcesoLampLabel = uilabel(app.UIFigure);
            app.ProcesoLampLabel.Text = 'Proceso';
            app.ProcesoLamp = uilamp(app.UIFigure);
            app.ModelodecontornosactivosButton = uibutton(app.UIFigure, 'push');
            app.ModelodecontornosactivosButton.ButtonPushedFcn = createCallbackFcn(app, @ModelodecontornosactivosButtonPushed, true);
            app.ModelodecontornosactivosButton.Enable = 'off';
            app.ModelodecontornosactivosButton.Text = 'Modelo de contornos activos';
            app.SegmentarButton = uibutton(app.UIFigure, 'push');
            app.SegmentarButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentarButtonPushed, true);
            app.SegmentarButton.Text = 'Segmentar';
            app.TonosmximosSpinnerLabel = uilabel(app.UIFigure);
            app.TonosmximosSpinnerLabel.Text = 'Tonos máximos';
            app.TonosmximosSpinner = uispinner(app.UIFigure);
            app.TonosmximosSpinner.Limits = [1 256];
            app.TonosmximosSpinner.RoundFractionalValues = 'on';
            app.TonosmximosSpinner.ValueChangedFcn = createCallbackFcn(app, @TonosmximosSpinnerValueChanged, true);
            app.TonosmximosSpinner.Value = 180;
            app.TonosmnimosSpinnerLabel = uilabel(app.UIFigure);
            app.TonosmnimosSpinnerLabel.Text = 'Tonos mínimos';
            app.TonosmnimosSpinner = uispinner(app.UIFigure);
            app.TonosmnimosSpinner.Limits = [1 256];
            app.TonosmnimosSpinner.RoundFractionalValues = 'on';
            app.TonosmnimosSpinner.ValueChangedFcn = createCallbackFcn(app, @TonosmnimosSpinnerValueChanged, true);
            app.TonosmnimosSpinner.Value = 100;
            app.AreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AreaEditField.Limits = [500 1400];
            app.AreaEditField.RoundFractionalValues = 'on';
            app.AreaEditField.Value = 1000;
            app.reaparaloscomponentesconexosaencontrarLabel = uilabel(app.UIFigure);
            app.reaparaloscomponentesconexosaencontrarLabel.Enable = 'off';
            app.reaparaloscomponentesconexosaencontrarLabel.Text = 'Área para los componentes conexos a encontrar';
            app.ImagenoriginalLabel = uilabel(app.UIFigure);
            app.ImagenoriginalLabel.Text = 'Imagen original';
            app.ResultadosLabel = uilabel(app.UIFigure);
            app.ResultadosLabel.Text = 'Resultados';
            app.CambiosaplicadosLabel = uilabel(app.UIFigure);
            app.CambiosaplicadosLabel.Text = 'Cambios aplicados';
            app.TamaodelelementoescturantesuavizadoLabel = uilabel(app.UIFigure);
            app.TamaodelelementoescturantesuavizadoLabel.Enable = 'off';
            app.TamaodelelementoescturantesuavizadoLabel.Text = 'Tamaño del elemento escturante (suavizado)';
            app.TamaodelelementoestructurantesegmentacinLabel = uilabel(app.UIFigure);
            app.TamaodelelementoestructurantesegmentacinLabel.Enable = 'off';
            app.TamaodelelementoestructurantesegmentacinLabel.Text = 'Tamaño del elemento estructurante (segmentación)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SUT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end